classdef Code_1769415 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CONFIGURATIONPanel              matlab.ui.container.Panel
        SquareorNeedleSizeEditFieldLabel  matlab.ui.control.Label
        SquareorNeedleSizeEditField     matlab.ui.control.NumericEditField
        PlanksorTileSizeEditFieldLabel  matlab.ui.control.Label
        PlanksorTileSizeEditField       matlab.ui.control.NumericEditField
        HowmanySquaresorNeedlesEditFieldLabel  matlab.ui.control.Label
        HowmanySquaresorNeedlesEditField  matlab.ui.control.NumericEditField
        HowManyPlanksEditFieldLabel     matlab.ui.control.Label
        HowManyPlanksEditField          matlab.ui.control.NumericEditField
        NumberofYaxisTilesEditFieldLabel  matlab.ui.control.Label
        NumberofYaxisTilesEditField     matlab.ui.control.NumericEditField
        NumberofXaxisTilesEditFieldLabel  matlab.ui.control.Label
        NumberofXaxisTilesEditField     matlab.ui.control.NumericEditField
        RunSimulationButton             matlab.ui.control.Button
        RenderButton                    matlab.ui.control.StateButton
        NumberofneedlestohighlightEditFieldLabel  matlab.ui.control.Label
        NumberofneedlestohighlightEditField  matlab.ui.control.NumericEditField
        TASKPanel                       matlab.ui.container.ButtonGroup
        DropNeedlesontoPlanksButton     matlab.ui.control.ToggleButton
        DropSquaresontoPlanksButton     matlab.ui.control.ToggleButton
        DropNeedlesontoTilesButton      matlab.ui.control.ToggleButton
        ESTIMATESPanel                  matlab.ui.container.Panel
        PisEstimateTextAreaLabel        matlab.ui.control.Label
        PisEstimateTextArea             matlab.ui.control.TextArea
        Root2sEstimateTextAreaLabel     matlab.ui.control.Label
        Root2sEstimateTextArea          matlab.ui.control.TextArea
        Graphic                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            event = 0;
            clc;
            app.DropSquaresontoPlanksButton.Value = 1;
            app.DropNeedlesontoPlanksButton.Value = 0;
            RunSim(app, event)
        end

        % Button pushed function: RunSimulationButton
        function RunSim(app, event)
            % Plots using default values
            nPlanks = app.HowManyPlanksEditField.Value;
            nNeedles = app.HowmanySquaresorNeedlesEditField.Value;
            plankSize = app.PlanksorTileSizeEditField.Value;
            needleSize = app.SquareorNeedleSizeEditField.Value;
            nTilesX = app.NumberofXaxisTilesEditField.Value;
            nTilesY = app.NumberofYaxisTilesEditField.Value;
            render = app.RenderButton.Value;
            nHighlighted = app.NumberofneedlestohighlightEditField.Value;
            
                        
            if app.DropNeedlesontoPlanksButton.Value == 1
                simWidth = plankSize*nPlanks;
                
                % Plots the needles
                needleX1 = rand(1, nNeedles)*(simWidth - needleSize);
                needleY1 = rand(1, nNeedles)*simWidth;
                needleX2 = zeros(1, nNeedles);
                needleY2 = zeros(1, nNeedles);
                
                crossing = zeros(1, nNeedles);
                needleTheta = zeros(1, nNeedles);
                
                for i = 1:nNeedles
                    x1 = needleX1(i);
                    y1 = needleY1(i);
                    
                    theta = rand()*pi - pi/2;
                    needleTheta(i) = theta;
                    
                    x2 = x1 + cos(theta)*needleSize;
                    y2 = y1 + sin(theta)*needleSize;
                    
                    if floor(x1/plankSize) ~= floor(x2/plankSize)
                        crossing(i) = 1;
                    end
                    
                    needleX2(i) = x2;
                    needleY2(i) = y2;
                
                end
                
                crossing = logical(crossing);
                
                if render
                    
                    plankLines = linspace(plankSize, simWidth - plankSize, nPlanks - 1);
                    
                    % Plank Plotting
                    plot(app.Graphic, [plankLines; plankLines], [ones(1, nPlanks - 1)*simWidth; zeros(1, nPlanks - 1)], "Color", "k")
                    
                    needleColors = repmat([0, 0, 1], nNeedles, 1);
                    needleColors(crossing, :) = repmat([1, 0, 0], sum(crossing), 1);
                    app.Graphic.ColorOrder =  needleColors;
                    app.Graphic.ColorOrderIndex = 1;
                    
                    hold(app.Graphic, "on")
                    
                    % Needle highlighting
                    srcPre = 0;
                    colorPre = 0;
                    preTheta = [];
                    preThetaColor = double.empty(0, 3);
                    prevCloseL = [];
                    prevCloseLColor = double.empty(0, 3);
                    j(1) = plot(app.Graphic, [1;2], [3;4], "LineStyle","none");
                    
                    for i = 1:nNeedles
                        h(i) = plot(app.Graphic, [needleX1(i); needleX2(i)], [needleY1(i); needleY2(i)],'ButtonDownFcn',{@lineCallback}, "LineWidth", 3);
                    end
                    
                    % Basic formatting
                    app.Graphic.XLim = [0, simWidth];
                    app.Graphic.YLim = [0, simWidth];
                    hold(app.Graphic, "off")
                    axis(app.Graphic, "square")
                    
                else
                    
                    cla(app.Graphic)
                    
                end
                
                piValue = sum(crossing)/nNeedles/2*(plankSize/needleSize)*10;
                                
                app.PisEstimateTextArea.Value = sprintf('%0.3f', piValue);
                app.Root2sEstimateTextArea.Value = "N/A";
                
            elseif app.DropNeedlesontoTilesButton.Value == 1
                simWidthX = plankSize*nTilesX;
                simWidthY = plankSize*nTilesY;
                
                % Plots the needles
                needleX1 = rand(1, nNeedles)*(simWidthX - needleSize);
                needleY1 = rand(1, nNeedles)*simWidthY;
                needleX2 = zeros(1, nNeedles);
                needleY2 = zeros(1, nNeedles);
                
                crossing = zeros(1, nNeedles);

                needleTheta = zeros(1, nNeedles);
                
                for i = 1:nNeedles
                    
                    x1 = needleX1(i);
                    y1 = needleY1(i);
                    
                    theta = rand()*pi - pi/2;
                    needleTheta(i) = theta;
                    
                    x2 = x1 + cos(theta)*needleSize;
                    y2 = y1 + sin(theta)*needleSize;
                    
                    if floor(x1/plankSize) ~= floor(x2/plankSize) || floor(y1/plankSize) ~= floor(y2/plankSize)
                        crossing(i) = 1;
                    end
                    
                    needleX2(i) = x2;
                    needleY2(i) = y2;
                
                end
                
                crossing = logical(crossing);
                
                if render
                        
                    tLinesX = linspace(plankSize, simWidthX - plankSize, nTilesX - 1);
                    tLinesY = linspace(plankSize, simWidthY - plankSize, nTilesY - 1);
                    
                    % If tile option is selected
                    plot(app.Graphic, [tLinesX; tLinesX], [ones(1, nTilesX - 1)*simWidthX; zeros(1, nTilesX - 1)], "Color", "k")
                    hold(app.Graphic, "on")
                    plot(app.Graphic, [ones(1, nTilesY - 1)*simWidthY; zeros(1, nTilesY - 1)], [tLinesY; tLinesY], "Color", "k")
                    hold(app.Graphic, "off")
                    
                    needleColors = repmat([0, 0, 1], nNeedles, 1);
                    needleColors(crossing, :) = repmat([1, 0, 0], sum(crossing), 1);
                    app.Graphic.ColorOrder =  needleColors;
                    app.Graphic.ColorOrderIndex = 1;
                    
                    hold(app.Graphic, "on")
                    
                    % Needle Highlighting
                    
                    srcPre = 0;
                    colorPre = 0;
                    preTheta = [];
                    preThetaColor = double.empty(0, 3);
                    prevCloseL = [];
                    prevCloseLColor = double.empty(0, 3);
                    j(1) = plot(app.Graphic, [1;2], [3;4], "LineStyle","none");
                    for i = 1:nNeedles
                        h(i) = plot(app.Graphic, [needleX1(i); needleX2(i)], [needleY1(i); needleY2(i)],'ButtonDownFcn',{@lineCallback}, "LineWidth", 3);
                    end
                    
                    % Basic formatting
                    app.Graphic.XLim = [0, simWidthX];
                    app.Graphic.YLim = [0, simWidthY];
                    hold(app.Graphic, "off")
                    axis(app.Graphic, "square")
                    
                else
                    
                    cla(app.Graphic)
                    
                end
                % Pi calculation               
                piValue = (4*needleSize*plankSize-needleSize^2)/(plankSize^2)*(nNeedles/sum(crossing));
                                
                app.PisEstimateTextArea.Value = sprintf('%0.3f', piValue);
                app.Root2sEstimateTextArea.Value = "N/A";
                
            else
                
                simWidth = plankSize*nPlanks;
                                
                % Needle plotting
                needleX1 = rand(1, nNeedles)*(simWidth - needleSize*2^(1/2) - needleSize*2^(1/2)/2) + needleSize*2^(1/2)/2;
                needleY1 = rand(1, nNeedles)*simWidth;
                needleX2 = zeros(1, nNeedles);
                needleY2 = zeros(1, nNeedles);
                needleX3 = zeros(1, nNeedles);
                needleY3 = zeros(1, nNeedles);
                needleX4 = zeros(1, nNeedles);
                needleY4 = zeros(1, nNeedles);
                
                crossing = zeros(1, nNeedles);
                crossingConsec = 0;
                
                needleTheta = zeros(1, nNeedles);
                
                for i = 1:nNeedles
                    
                    x1 = needleX1(i);
                    y1 = needleY1(i);
                    
                    theta = rand()*pi - pi/2;
                    needleTheta(i) = theta;
                    
                    x2 = x1 + cos(theta)*needleSize;
                    y2 = y1 + sin(theta)*needleSize;
                    
                    x3 = x1 + cos(theta-pi/4)*needleSize*(2^(1/2));
                    y3 = y1 + sin(theta-pi/4)*needleSize*(2^(1/2));
                    
                    x4 = x1 + cos(theta-pi/2)*needleSize;
                    y4 = y1 + sin(theta-pi/2)*needleSize;
                    
                    v = [floor(x1/plankSize), floor(x2/plankSize), floor(x3/plankSize), floor(x4/plankSize)];
                    
                    if ~all(v == v(1))
                        crossing(i) = 1;
                        
                        if sum(v == v(1)) == 3 || sum(v == v(2)) == 3 || sum(v == v(3)) == 3 || sum(v == v(4)) == 3 
                            crossingConsec = crossingConsec + 1;                            
                        end
                    end
                    
                    needleX2(i) = x2;
                    needleY2(i) = y2;
                    needleX3(i) = x3;
                    needleY3(i) = y3;
                    needleX4(i) = x4;
                    needleY4(i) = y4;
                
                end
                
                crossing = logical(crossing);
                
                if render
                    
                    plankLines = linspace(plankSize, simWidth - plankSize, nPlanks - 1);
                    
                    % Plank plotting
                    plot(app.Graphic, [plankLines; plankLines], [ones(1, nPlanks - 1)*simWidth; zeros(1, nPlanks - 1)], "Color", "k")
                
                    needleColors = ones(nNeedles, 1, 3);
                    needleColors(:, 1, 1:2) = zeros(nNeedles, 1, 2);
                    needleColors(crossing, 1, 3) = zeros(sum(crossing), 1, 1);
                    needleColors(crossing, 1, 1) = ones(sum(crossing), 1, 1);
                    
                    hold(app.Graphic, "on")
                    
                    % Needle highlighting
                    
                    srcPre = 0;
                    colorPre = 0;
                    for i = 1:nNeedles
                       patch(app.Graphic, [needleX1(i);needleX2(i);needleX3(i);needleX4(i)], [needleY1(i);needleY2(i);needleY3(i);needleY4(i)], needleColors(i, 1, 1:3),'ButtonDownFcn',{@squareCallback});
                    end
                    
                    % Basic plotting
                    app.Graphic.XLim = [0, simWidth];
                    app.Graphic.YLim = [0, simWidth];
                    hold(app.Graphic, "off")
                    axis(app.Graphic, "square")
                        
                else
                    
                    cla(app.Graphic)
                    
                end
                                
                piValue = (nNeedles/sum(crossing))*4*needleSize/plankSize;
                valueOfRoot2 = 2 - (crossingConsec/nNeedles)/(sum(crossing)/nNeedles);
                                
                app.PisEstimateTextArea.Value = sprintf('%0.3f', piValue);
                app.Root2sEstimateTextArea.Value = sprintf('%0.3f', valueOfRoot2);
                
            end
            
            
            function lineCallback(src, ~)
                
                if srcPre ~= 0
                    % Restores prior colour
                    srcPre.Color = colorPre; 
                    for i = 1:length(prevCloseL)
                        h(prevCloseL(i)).Color = prevCloseLColor(i, 1:3);
                    end
                    for i = 1:length(preTheta)
                        h(preTheta(i)).Color = preThetaColor(i, 1:3);
                    end
                    for i = 1:length(j)
                        delete(j(i));
                    end
                end
        
                srcPre = src;
                colorPre = src.Color;
                
                % Highlights similar oritented needles.
                x = src.XData;
                y = src.YData;
                
                currentTheta = atan((max(y)-min(y))/(max(x)-min(x)));
                                                
                v = sort(needleTheta(:));
                for i = 1:nHighlighted+1
                    result = interp1(v, v, currentTheta, 'nearest');
                    
                    if ~isnan(result)
                        indexB = find(needleTheta == result);
                        v(v == result) = [];
                        preTheta(i) = indexB;
                        preThetaColor(i, 1:3) = h(indexB).Color;
                        h(indexB).Color = [1, 0, 1];
                    end
                    
                end
                
                % Highlights the closest needles number of which depends on
                % user config.
                
                distList = ((needleX2 - needleX1)/2 + needleX1 - ((x(2)-x(1))/2 + x(1))).^2 + ((needleY2 - needleY1)/2 + needleY1 - ((y(2)-y(1))/2 + y(1))).^2;
                
                sortedDistList = sort(distList);
                nDists(1:nHighlighted) = sortedDistList(2:nHighlighted+1);
                hold(app.Graphic, "on")
                
                for i = 1:nHighlighted
                    
                    v = nDists(i);
                    
                    indexV = find(distList == v);
                    prevCloseL(i) = indexV;
                    prevCloseLColor(i, 1:3) = h(indexV).Color;
                    h(indexV).Color = [0, 1, 1];
                    
                    % Rays
                    line2X = h(indexV).XData;
                    line2Y = h(indexV).YData;
                    
                    xRay = [(x(2) - x(1))/2 + x(1); (line2X(2) - line2X(1))/2 + line2X(1)];
                    yRay = [(y(2) - y(1))/2 + y(1); (line2Y(2) - line2Y(1))/2 + line2Y(1)];
                    j(i) = plot(app.Graphic, xRay, yRay, "Color", [.5, 1, .5]);
                    
                end
                
                hold(app.Graphic, "off")
                
                
                % Colour change
                src.Color = 'yellow';
                
            end
            
            function squareCallback(src, ~)
                
                if srcPre ~= 0
                    % Restores prior colour
                    srcPre.FaceColor = colorPre;
                end
                
                % Colours fetched
                srcPre = src;
                colorPre = src.FaceColor;
                
                % change color of selected needle
                src.FaceColor = 'yellow';
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CONFIGURATIONPanel = uipanel(app.GridLayout);
            app.CONFIGURATIONPanel.Title = 'CONFIGURATION';
            app.SquareorNeedleSizeEditFieldLabel = uilabel(app.CONFIGURATIONPanel);
            app.SquareorNeedleSizeEditFieldLabel.Text = 'Square or Needle Size?';
            app.SquareorNeedleSizeEditField = uieditfield(app.CONFIGURATIONPanel, 'numeric');
            app.SquareorNeedleSizeEditField.Value = 1;
            app.PlanksorTileSizeEditFieldLabel = uilabel(app.CONFIGURATIONPanel);
            app.PlanksorTileSizeEditFieldLabel.Text = 'Planks or Tile Size?';
            app.PlanksorTileSizeEditField = uieditfield(app.CONFIGURATIONPanel, 'numeric');
            app.PlanksorTileSizeEditField.Value = 2;
            app.HowmanySquaresorNeedlesEditFieldLabel = uilabel(app.CONFIGURATIONPanel);
            app.HowmanySquaresorNeedlesEditFieldLabel.Text = 'How many Squares or Needles?';
            app.HowmanySquaresorNeedlesEditField = uieditfield(app.CONFIGURATIONPanel, 'numeric');
            app.HowmanySquaresorNeedlesEditField.Value = 10;
            app.HowManyPlanksEditFieldLabel = uilabel(app.CONFIGURATIONPanel);
            app.HowManyPlanksEditFieldLabel.Text = 'How Many Planks?';
            app.HowManyPlanksEditField = uieditfield(app.CONFIGURATIONPanel, 'numeric');
            app.HowManyPlanksEditField.Value = 10;
            app.NumberofYaxisTilesEditFieldLabel = uilabel(app.CONFIGURATIONPanel);
            app.NumberofYaxisTilesEditFieldLabel.Text = 'Number of  Y axis Tiles';
            app.NumberofYaxisTilesEditField = uieditfield(app.CONFIGURATIONPanel, 'numeric');
            app.NumberofYaxisTilesEditField.Value = 10;
            app.NumberofXaxisTilesEditFieldLabel = uilabel(app.CONFIGURATIONPanel);
            app.NumberofXaxisTilesEditFieldLabel.Text = 'Number of X axis Tiles ';
            app.NumberofXaxisTilesEditField = uieditfield(app.CONFIGURATIONPanel, 'numeric');
            app.NumberofXaxisTilesEditField.Value = 10;
            app.RunSimulationButton = uibutton(app.CONFIGURATIONPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSim, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.RenderButton = uibutton(app.CONFIGURATIONPanel, 'state');
            app.RenderButton.Text = 'Render?';
            app.RenderButton.Value = true;
            app.NumberofneedlestohighlightEditFieldLabel = uilabel(app.CONFIGURATIONPanel);
            app.NumberofneedlestohighlightEditFieldLabel.Text = 'Number of needles to highlight?';
            app.NumberofneedlestohighlightEditField = uieditfield(app.CONFIGURATIONPanel, 'numeric');
            app.NumberofneedlestohighlightEditField.Limits = [1 Inf];
            app.NumberofneedlestohighlightEditField.RoundFractionalValues = 'on';
            app.NumberofneedlestohighlightEditField.ValueDisplayFormat = '%11g';
            app.NumberofneedlestohighlightEditField.Value = 3;
            app.TASKPanel = uibuttongroup(app.GridLayout);
            app.DropNeedlesontoPlanksButton = uitogglebutton(app.TASKPanel);
            app.DropNeedlesontoPlanksButton.Text = 'Drop Needles onto Planks';
            app.DropNeedlesontoPlanksButton.Value = true;
            app.DropSquaresontoPlanksButton = uitogglebutton(app.TASKPanel);
            app.DropSquaresontoPlanksButton.Text = 'Drop Squares onto Planks';
            app.DropNeedlesontoTilesButton = uitogglebutton(app.TASKPanel);
            app.DropNeedlesontoTilesButton.Text = 'Drop Needles onto Tiles';
            app.ESTIMATESPanel = uipanel(app.GridLayout);
            app.ESTIMATESPanel.Title = 'ESTIMATES';
            app.PisEstimateTextAreaLabel = uilabel(app.ESTIMATESPanel);
            app.PisEstimateTextAreaLabel.Text = 'Pi''s Estimate';
            app.PisEstimateTextArea = uitextarea(app.ESTIMATESPanel);
            app.Root2sEstimateTextAreaLabel = uilabel(app.ESTIMATESPanel);
            app.Root2sEstimateTextAreaLabel.Text = 'Root 2''s Estimate';
            app.Root2sEstimateTextArea = uitextarea(app.ESTIMATESPanel);
            app.Graphic = uiaxes(app.GridLayout);
            title(app.Graphic, 'SIMULATED NEEDLE DROPPING')
            xlabel(app.Graphic, 'X')
            ylabel(app.Graphic, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Code_1769415

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end